classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        OpenMenu                     matlab.ui.container.Menu
        SaveAsMenu                   matlab.ui.container.Menu
        TabGroup                     matlab.ui.container.TabGroup
        AdditiveNoiseTab             matlab.ui.container.Tab
        GoButton                     matlab.ui.control.Button
        TabGroup2_2                  matlab.ui.container.TabGroup
        GaussianTab_2                matlab.ui.container.Tab
        bSpinner                     matlab.ui.control.Spinner
        bSpinnerLabel                matlab.ui.control.Label
        aSpinner                     matlab.ui.control.Spinner
        aSpinnerLabel                matlab.ui.control.Label
        kSpinner                     matlab.ui.control.Spinner
        kSpinnerLabel                matlab.ui.control.Label
        radiusCSpinner_2             matlab.ui.control.Spinner
        radiusCLabel                 matlab.ui.control.Label
        HuvkexpauvLabel              matlab.ui.control.Label
        RayleighTab_2                matlab.ui.container.Tab
        ErlangTab_2                  matlab.ui.container.Tab
        ExpTab_2                     matlab.ui.container.Tab
        UniformTab_2                 matlab.ui.container.Tab
        ImpulseTab_2                 matlab.ui.container.Tab
        MaxWindowSizeSliderLabel     matlab.ui.control.Label
        MaxWindowSizeSlider          matlab.ui.control.Slider
        AdaptiveMedianFilterLabel_2  matlab.ui.control.Label
        StartWindowSizeSliderLabel   matlab.ui.control.Label
        StartWindowSizeSlider        matlab.ui.control.Slider
        MultiplicativeNoiseTab       matlab.ui.container.Tab
        GoButton_2                   matlab.ui.control.Button
        TabGroup2                    matlab.ui.container.TabGroup
        GaussianTab                  matlab.ui.container.Tab
        Spinner_2                    matlab.ui.control.Spinner
        Label_3                      matlab.ui.control.Label
        KSpinner                     matlab.ui.control.Spinner
        KSpinnerLabel                matlab.ui.control.Label
        Label_4                      matlab.ui.control.Label
        radiusCSpinner               matlab.ui.control.Spinner
        radiusCSpinnerLabel          matlab.ui.control.Label
        RayleighTab                  matlab.ui.container.Tab
        ErlangTab                    matlab.ui.container.Tab
        ExpTab                       matlab.ui.container.Tab
        UniformTab                   matlab.ui.container.Tab
        ImpulseTab                   matlab.ui.container.Tab
        UIAxes                       matlab.ui.control.UIAxes
        SpectrumButton               matlab.ui.control.Button
        ResetButton                  matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
    end

    
    properties (Access = private)
        img_reg % Description
%         img_processing
    end
    

    methods (Access = private)

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            % initialize
            addpath(genpath('.'));
%             try
%                 app.img_processing = ImageClass('res/processing.png');
%             catch ME
%                 fprintf('%s', ME.identifier);
%             end
            [f_name, f_path] = uigetfile('*.*', 'Select file');
            img_path = strcat(f_path, f_name);
            app.img_reg = ImageRegister(img_path);
            imshow(app.img_reg.getimg().getimg_uint8(), 'Parent', app.UIAxes);
            app.Label_2.Text = app.img_reg.getimg().getsize_char();
        end

        % Button pushed function: SpectrumButton
        function SpectrumButtonPushed(app, event)
            sp = app.img_reg.getimg().getspectrum();
            display_spctrm(sp);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.img_reg.reset();
            imshow(app.img_reg.getimg().getimg_uint8(), 'Parent', app.UIAxes);
        end

        % Value changed function: StartWindowSizeSlider
        function StartWindowSizeSliderValueChanged(app, event)
            value = round(app.StartWindowSizeSlider.Value);
            sz = app.img_reg.getimg().getsize();
            if mod(value, 2) == 0
                value = value - 1;
            end
            if value > min(sz(1:2))
                value = min(sz(1:2));
            end
            app.StartWindowSizeSlider.Value = value;
            app.MaxWindowSizeSlider.Value = value;
        end

        % Value changed function: MaxWindowSizeSlider
        function MaxWindowSizeSliderValueChanged(app, event)
            value = round(app.MaxWindowSizeSlider.Value);
            sz = app.img_reg.getimg().getsize();
            if mod(value, 2) == 0
                value = value - 1;
            end
            if value > min(sz(1:2))
                value = min(sz(1:2));
            end
            if value < app.StartWindowSizeSlider.Value
                value = app.StartWindowSizeSlider.Value;
            end
            app.MaxWindowSizeSlider.Value = value;
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            app.Label.Text = 'Processing ...';
            pause(0.1);
%             imshow(app.img_processing.getimg_uint8(), 'Parent', app.UIAxes);
            img0 = app.img_reg.getimg().getimg_uint8();
            v_lo = app.StartWindowSizeSlider.Value;
            v_hi = app.MaxWindowSizeSlider.Value;
            img1 = adaptive_median_3d(img0, [v_lo, v_lo], [v_hi, v_hi]);
            app.img_reg.newimg(img1);
            imshow(img1, 'Parent', app.UIAxes);
            app.Label.Text = '';
        end

        % Button pushed function: GoButton_2
        function GoButton_2Pushed(app, event)
    app.Label.Text = 'Processing ...';
    pause(0.1);
    %             imshow(app.img_processing.getimg_uint8(), 'Parent', app.UIAxes);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.Text = 'Save As';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AdditiveNoiseTab = uitab(app.TabGroup);
            app.AdditiveNoiseTab.Title = 'Additive Noise';
            app.GoButton = uibutton(app.AdditiveNoiseTab, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.TabGroup2_2 = uitabgroup(app.AdditiveNoiseTab);
            app.GaussianTab_2 = uitab(app.TabGroup2_2);
            app.GaussianTab_2.Title = 'Gaussian';
            app.bSpinner = uispinner(app.GaussianTab_2);
            app.bSpinnerLabel = uilabel(app.GaussianTab_2);
            app.bSpinnerLabel.Text = 'b';
            app.aSpinner = uispinner(app.GaussianTab_2);
            app.aSpinnerLabel = uilabel(app.GaussianTab_2);
            app.aSpinnerLabel.Text = 'a';
            app.kSpinner = uispinner(app.GaussianTab_2);
            app.kSpinnerLabel = uilabel(app.GaussianTab_2);
            app.kSpinnerLabel.Text = 'k';
            app.radiusCSpinner_2 = uispinner(app.GaussianTab_2);
            app.radiusCLabel = uilabel(app.GaussianTab_2);
            app.radiusCLabel.Text = 'radius (C)';
            app.HuvkexpauvLabel = uilabel(app.GaussianTab_2);
            app.HuvkexpauvLabel.Text = {'H(u‚ v)'; '= k exp(-a(u²+v²)ᵇ)'};
            app.RayleighTab_2 = uitab(app.TabGroup2_2);
            app.RayleighTab_2.Title = 'Rayleigh';
            app.ErlangTab_2 = uitab(app.TabGroup2_2);
            app.ErlangTab_2.Title = 'Erlang';
            app.ExpTab_2 = uitab(app.TabGroup2_2);
            app.ExpTab_2.Title = 'Exp';
            app.UniformTab_2 = uitab(app.TabGroup2_2);
            app.UniformTab_2.Title = 'Uniform';
            app.ImpulseTab_2 = uitab(app.TabGroup2_2);
            app.ImpulseTab_2.Title = 'Impulse';
            app.MaxWindowSizeSliderLabel = uilabel(app.ImpulseTab_2);
            app.MaxWindowSizeSliderLabel.Text = 'Max Window Size';
            app.MaxWindowSizeSlider = uislider(app.ImpulseTab_2);
            app.MaxWindowSizeSlider.Limits = [0 25];
            app.MaxWindowSizeSlider.MajorTicks = [3 5 7 9 11 13 15 17 19 21 23 25];
            app.MaxWindowSizeSlider.MajorTickLabels = {'3', '5', '7', '9', '11', '13', '15', '17', '19', '21', '23', '25', ''};
            app.MaxWindowSizeSlider.ValueChangedFcn = createCallbackFcn(app, @MaxWindowSizeSliderValueChanged, true);
            app.MaxWindowSizeSlider.MinorTicks = [];
            app.MaxWindowSizeSlider.Value = 3;
            app.AdaptiveMedianFilterLabel_2 = uilabel(app.ImpulseTab_2);
            app.AdaptiveMedianFilterLabel_2.Text = {'Adaptive Median '; 'Filter'};
            app.StartWindowSizeSliderLabel = uilabel(app.AdditiveNoiseTab);
            app.StartWindowSizeSliderLabel.Text = 'Start Window Size';
            app.StartWindowSizeSlider = uislider(app.AdditiveNoiseTab);
            app.StartWindowSizeSlider.Limits = [0 25];
            app.StartWindowSizeSlider.MajorTicks = [3 5 7 9 11 13 15 17 19 21 23 25];
            app.StartWindowSizeSlider.MajorTickLabels = {'3', '5', '7', '9', '11', '13', '15', '17', '19', '21', '23', '25', ''};
            app.StartWindowSizeSlider.ValueChangedFcn = createCallbackFcn(app, @StartWindowSizeSliderValueChanged, true);
            app.StartWindowSizeSlider.MinorTicks = [];
            app.StartWindowSizeSlider.Value = 3;
            app.MultiplicativeNoiseTab = uitab(app.TabGroup);
            app.MultiplicativeNoiseTab.Title = 'Multiplicative Noise';
            app.GoButton_2 = uibutton(app.MultiplicativeNoiseTab, 'push');
            app.GoButton_2.ButtonPushedFcn = createCallbackFcn(app, @GoButton_2Pushed, true);
            app.GoButton_2.Text = 'Go';
            app.TabGroup2 = uitabgroup(app.MultiplicativeNoiseTab);
            app.GaussianTab = uitab(app.TabGroup2);
            app.GaussianTab.Title = 'Gaussian';
            app.Spinner_2 = uispinner(app.GaussianTab);
            app.Label_3 = uilabel(app.GaussianTab);
            app.Label_3.Text = 'α';
            app.KSpinner = uispinner(app.GaussianTab);
            app.KSpinnerLabel = uilabel(app.GaussianTab);
            app.KSpinnerLabel.Text = 'K';
            app.Label_4 = uilabel(app.GaussianTab);
            app.radiusCSpinner = uispinner(app.GaussianTab);
            app.radiusCSpinnerLabel = uilabel(app.GaussianTab);
            app.radiusCSpinnerLabel.Text = 'radius (C)';
            app.RayleighTab = uitab(app.TabGroup2);
            app.RayleighTab.Title = 'Rayleigh';
            app.ErlangTab = uitab(app.TabGroup2);
            app.ErlangTab.Title = 'Erlang';
            app.ExpTab = uitab(app.TabGroup2);
            app.ExpTab.Title = 'Exp';
            app.UniformTab = uitab(app.TabGroup2);
            app.UniformTab.Title = 'Uniform';
            app.ImpulseTab = uitab(app.TabGroup2);
            app.ImpulseTab.Title = 'Impulse';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.SpectrumButton = uibutton(app.UIFigure, 'push');
            app.SpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @SpectrumButtonPushed, true);
            app.SpectrumButton.Text = 'Spectrum';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end